classdef Emulation_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        CreateDataButton               matlab.ui.control.Button
        ConfigFileTextArea             matlab.ui.control.TextArea
        ConfigFileTextAreaLabel        matlab.ui.control.Label
        CurrentFileTextArea            matlab.ui.control.TextArea
        CurrentFileTextAreaLabel       matlab.ui.control.Label
        OpenImageButton                matlab.ui.control.Button
        Image                          matlab.ui.control.Image
        CSCameraEmulatorSoftwareLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        Property % Description
        LoadedImagePath char = '';
        LoadedConfigPath char = '';
        LoadedConfig struct;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Position = [100, 100, 800, 500];
        end

        % Button pushed function: OpenImageButton
        function OpenImageButtonPushed(app, event)
            % 1. Open the Image File
            [imgFile, imgPath] = uigetfile({'*.png;*.jpg;*.jpeg;*.bmp;*.tiff', 'Image Files'; ...
                                            '*.*', 'All Files'}, 'Select an Image');
            figure(app.UIFigure);
            
            if isequal(imgFile, 0)
                return;
            end
            
            app.LoadedImagePath = fullfile(imgPath, imgFile);
            app.CurrentFileTextArea.Value = app.LoadedImagePath;
            
            % 2. Automatically prompt for the Config File (JSON)
            [cfgFile, cfgPath] = uigetfile({'*.json', 'JSON Files'; ...
                                            '*.*', 'All Files'}, 'Select Camera Config File');
            figure(app.UIFigure);
            
            if isequal(cfgFile, 0)
                return;
            end
            
            app.LoadedConfigPath = fullfile(cfgPath, cfgFile);
            
            try
                % Read the raw text from the file
                rawText = fileread(app.LoadedConfigPath);
                
                % Decode it into a MATLAB struct so we can pass it to the emulator function
                app.LoadedConfig = jsondecode(rawText);
                
                % Split the raw text by line breaks so it displays correctly in a UI Text Area
                app.ConfigFileTextArea.Value = splitlines(rawText);
                
                % Enable the Create Data button now that everything is loaded
                app.CreateDataButton.Enable = 'on';
            catch ME
                uialert(app.UIFigure, ['Error reading config JSON: ', ME.message], 'Config Error');
            end            
        end

        % Button pushed function: CreateDataButton
        function CreateDataButtonPushed(app, event)
            app.CreateDataButton.Enable = 'off';
            app.CreateDataButton.Text = 'Processing...';
            app.UIFigure.Pointer = 'watch'; 
            drawnow; 
            
            try
                % Check if /data/ directory exists in current working directory, create if not
                dataDir = fullfile(pwd, 'data');
                if ~exist(dataDir, 'dir')
                    mkdir(dataDir);
                end
                
                % Extract parameters from config
                cfg = app.LoadedConfig;
                
                % Generate the proper maskList using the utils namespace
                maskList = utils.selectMaskIndexes(...
                    utils.getOptimalCore(cfg.sampling_parameters.resolution, cfg.sampling_parameters.core_mask_count), ...
                    cfg.sampling_parameters.sampling_pct, ...
                    cfg.sampling_parameters.resolution);
                
                % Call the emulation function (also updated to use the utils namespace if required)
                [camera_data, ~] = utils.emulateCameraCapture(app.LoadedImagePath, cfg, maskList);
                
                % Format the save file name based on the original image name
                [~, baseName, ~] = fileparts(app.LoadedImagePath);
                saveName = sprintf('%s_emulator_data.csv', baseName);
                savePath = fullfile(dataDir, saveName);
                
                % Write the output data to the /data/ folder
                writematrix(camera_data, savePath);
                
                uialert(app.UIFigure, ['Successfully saved emulator data to:', newline, savePath], 'Data Created', 'Icon', 'success');
                
            catch ME
                uialert(app.UIFigure, ['Emulator capture failed: ', ME.message], 'Simulation Error');
            end
            
            % Restore UI state
            app.CreateDataButton.Text = 'Create Data';
            app.CreateDataButton.Enable = 'on';
            app.UIFigure.Pointer = 'arrow';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.CSCameraEmulatorSoftwareLabel = uilabel(app.GridLayout);
            app.CSCameraEmulatorSoftwareLabel.Text = 'CSCamera Emulator Software';
            app.Image = uiimage(app.GridLayout);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'assets', 'eng_logo.png');
            app.OpenImageButton = uibutton(app.GridLayout, 'push');
            app.OpenImageButton.ButtonPushedFcn = createCallbackFcn(app, @OpenImageButtonPushed, true);
            app.OpenImageButton.Text = 'Open Image';
            app.CurrentFileTextAreaLabel = uilabel(app.GridLayout);
            app.CurrentFileTextAreaLabel.Text = 'Current File:';
            app.CurrentFileTextArea = uitextarea(app.GridLayout);
            app.CurrentFileTextArea.Editable = 'off';
            app.ConfigFileTextAreaLabel = uilabel(app.GridLayout);
            app.ConfigFileTextAreaLabel.Text = 'Config File:';
            app.ConfigFileTextArea = uitextarea(app.GridLayout);
            app.CreateDataButton = uibutton(app.GridLayout, 'push');
            app.CreateDataButton.ButtonPushedFcn = createCallbackFcn(app, @CreateDataButtonPushed, true);
            app.CreateDataButton.Text = 'Create Data';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Emulation_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end