classdef Reconstruction_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        ModeMenu                   matlab.ui.container.Menu
        SingleModeMenu             matlab.ui.container.Menu
        BulkModeMenu               matlab.ui.container.Menu
        GridLayout                 matlab.ui.container.GridLayout
        Panel                      matlab.ui.container.Panel
        GridLayout2                matlab.ui.container.GridLayout
        BulkFileListBox            matlab.ui.control.ListBox
        FilesListBoxLabel          matlab.ui.control.Label
        Image2                     matlab.ui.control.Image
        ReconstructImageButton     matlab.ui.control.Button
        SaveImageButton            matlab.ui.control.Button
        SelectReconstructionMethodListBox  matlab.ui.control.ListBox
        ReconstructionMethodLabel  matlab.ui.control.Label
        CurrentFileTextArea        matlab.ui.control.TextArea
        CurrentFileTextAreaLabel   matlab.ui.control.Label
        OpenFileButton             matlab.ui.control.Button
        Image                      matlab.ui.control.Image
        CSCameraReconstructionSoftwareLabel  matlab.ui.control.Label
        ReconstructedImagePanel    matlab.ui.container.Panel
    end

    
    properties (Access = private)
        VerboseMode logical = true; % Set to false to silence console prints
        LoadedData

        AppMode char = 'Single';
        BulkFiles cell = {};
        BulkPath char = '';
    end
    
    methods (Access = private)
        % Only print to the console if VerboseMode is turned on
        function logMsg(app, inputData)
            if app.VerboseMode
                % Check if the input is text (character vector or string)
                if ischar(inputData) || isstring(inputData)
                    disp(['[DEBUG] ', char(inputData)]);
                else
                    % If it is a table or matrix, print it on its own line
                    disp('[DEBUG] Data output:');
                    disp(inputData);
                end
            end
        end

        % Helper function to format and display images on the UI
        function displayReconstructedImage(app, imgMatrix)
            % Check if the matrix is a flat 2D grayscale image
            if ismatrix(imgMatrix) 
                % Convert to m-by-n-by-3 RGB array for the UI component
                rgbImage = repmat(imgMatrix, [1, 1, 3]);
            else
                rgbImage = imgMatrix; 
            end
            
            % Update the image component
            app.Image2.ImageSource = rgbImage;
            app.Image2.Visible = 'on';
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Set window size to 1024 pixels wide by 768 pixels tall
            app.UIFigure.Position = [100, 100, 1024, 768];
        end

        % Button pushed function: OpenFileButton
        function OpenFileButtonPushed(app, event)
            if strcmp(app.AppMode, 'Single')
                % Open the file dialog
                [file, path] = uigetfile({'*.csv', 'CSV Files'; ...
                                          '*.*', 'All Files'}, 'Select a CSV File');
                
                % Restore window to top of view
                figure(app.UIFigure);
    
                % Check if the user cancels/closes file select window.
                if isequal(file, 0)
                    return;
                end
    
                fullFileName = fullfile(path, file);
    
                % Update textbox with filename
                app.CurrentFileTextArea.Value = fullFileName;
    
                try
                    % Read as a matrix since the math function needs a dataMatrix
                    app.LoadedData = readmatrix(fullFileName);
                    
                    % 1. Log the text message
                    app.logMsg('Successfully loaded file. Previewing data:'); 
                    
                    % 2. Log a 5x5 snippet of the data matrix to the console
                    app.logMsg(app.LoadedData(1:min(5, end), 1:min(5, end))); 
                    
                    % 3. Wake up the Reconstruct button now that we have data!
                    app.ReconstructImageButton.Enable = 'on';
                    
                catch ME
                    uialert(app.UIFigure, ['Error reading CSV: ', ME.message], 'Import Error')
                end
            else
                % --- BULK MODE LOGIC ---
                [files, path] = uigetfile({'*.csv', 'CSV Files'}, ...
                    'Select Multiple CSV Files', 'MultiSelect', 'on');
                figure(app.UIFigure);
                
                if isequal(files, 0), return; end
                
                % uigetfile returns a char array if only 1 file is selected. 
                % Convert it to a cell array for consistent looping.
                if ischar(files)
                    files = {files};
                end
                
                app.BulkFiles = files;
                app.BulkPath = path;
                
                % --- UPDATE THE LIST BOX ---
                % Pass the cell array of file names directly to the List Box items
                app.BulkFileListBox.Items = files;
                
                % Update textbox (optional: you can remove this if you want the box completely blank!)
                app.CurrentFileTextArea.Value = sprintf('Ready to process %d files.', length(files));
                
                app.ReconstructImageButton.Enable = 'on';
                app.logMsg(sprintf('Loaded %d files for bulk processing.', length(files)));
            end
        end

        % Button pushed function: ReconstructImageButton
        function ReconstructImageButtonPushed(app, event)
            selectedMethod = app.SelectReconstructionMethodListBox.Value;
            
            if strcmp(app.AppMode, 'Single')
                % --- SINGLE MODE (Your Original Code) ---
                if isempty(app.CurrentFileTextArea.Value)
                    uialert(app.UIFigure, 'Please open a CSV file first.', 'Missing Data');
                    return;
                end
            
                app.ReconstructImageButton.Enable = 'off';
                app.ReconstructImageButton.Text = 'Processing...';
                app.UIFigure.Pointer = 'watch'; 
                drawnow; 
                
                try
                    app.logMsg(['Starting single reconstruction using: ', selectedMethod]);
                    switch selectedMethod
                        case 'Inverse Transform'
                            reconstructedImg = reco.simpleReconstruction(app.LoadedData);
                        case 'Total Variance'
                            reconstructedImg = reco.tvReconstruction(app.LoadedData);
                        case 'CNN'
                            reconstructedImg = reco.cnnReconstruction(app.LoadedData);
                    end
                    app.displayReconstructedImage(reconstructedImg);
                    app.SaveImageButton.Enable = 'on';
                catch ME
                    uialert(app.UIFigure, ['Reconstruction failed: ', ME.message], 'Error');
                end
                
                app.ReconstructImageButton.Text = 'Reconstruct Image';
                app.ReconstructImageButton.Enable = 'on';
                app.UIFigure.Pointer = 'arrow';
                
            else
                % --- BULK MODE LOGIC ---
                if isempty(app.BulkFiles)
                    uialert(app.UIFigure, 'No files selected for bulk processing.', 'Missing Data');
                    return;
                end
                
                % Ask user where to save the bulk results
                outDir = uigetdir(app.BulkPath, 'Select Output Folder for Bulk Reconstructions');
                if isequal(outDir, 0), return; end % User canceled
                
                app.ReconstructImageButton.Enable = 'off';
                app.ReconstructImageButton.Text = 'Processing Bulk...';
                
                % Create a Progress Dialog
                d = uiprogressdlg(app.UIFigure, 'Title', 'Bulk Reconstruction', ...
                    'Message', 'Starting process...', 'Cancelable', 'on');
                
                numFiles = length(app.BulkFiles);
                safeMethodName = strrep(char(selectedMethod), ' ', '_');
                
                try
                    for i = 1:numFiles
                        % Check for user cancellation
                        if d.CancelRequested
                            break;
                        end
                        
                        % Update Progress Bar
                        d.Value = i / numFiles;
                        d.Message = sprintf('Processing file %d of %d...', i, numFiles);
                        
                        % 1. Load Data
                        currentFileName = app.BulkFiles{i};
                        fullPath = fullfile(app.BulkPath, currentFileName);
                        data = readmatrix(fullPath);
                        
                        % 2. Reconstruct
                        switch selectedMethod
                            case 'Inverse Transform'
                                imgData = reco.simpleReconstruction(data);
                            case 'Total Variance'
                                imgData = reco.tvReconstruction(data);
                            case 'CNN'
                                imgData = reco.cnnReconstruction(data); % Placeholder
                        end
                        
                        % 3. Format Image Data for Saving
                        if ismatrix(imgData)
                            imgData = repmat(imgData, [1, 1, 3]);
                        end
                        
                        % 4. Save Image Automatically
                        [~, baseName, ~] = fileparts(currentFileName);
                        saveName = sprintf('%s_%s.png', baseName, safeMethodName);
                        savePath = fullfile(outDir, saveName);
                        imwrite(imgData, savePath);
                        
                        app.logMsg(['Saved bulk image: ', saveName]);
                        
                        % Optionally display the last image processed on the UI
                        if i == numFiles
                            app.displayReconstructedImage(imgData);
                        end
                    end
                    app.CurrentFileTextArea.Value = 'Bulk processing complete.';
                catch ME
                    uialert(app.UIFigure, ['Bulk processing failed on file ' currentFileName ': ', ME.message], 'Error');
                end
                
                % Cleanup and restore UI
                close(d);
                app.ReconstructImageButton.Text = 'Reconstruct Image';
                app.ReconstructImageButton.Enable = 'on';
            end
        end

        % Button pushed function: SaveImageButton
        function SaveImageButtonPushed(app, event)
            % 1. Safety check: Double confirm there is an image
            if isempty(app.Image2.ImageSource)
                uialert(app.UIFigure, 'No image available to save.', 'Save Error');
                return;
            end
            
            % Grab the text area value. If it's a cell array, extract the first line using {1}
            currentFilePath = app.CurrentFileTextArea.Value;
            if iscell(currentFilePath)
                currentFilePath = currentFilePath{1};
            end
            
            % Extract the base name
            [~, baseName, ~] = fileparts(currentFilePath);
            
            % Do the exact same extraction for the List Box value
            methodName = app.SelectReconstructionMethodListBox.Value;
            if iscell(methodName)
                methodName = methodName{1};
            end
            
            % Convert both strictly to characters and replace spaces with underscores
            baseName = char(baseName);
            safeMethodName = strrep(char(methodName), ' ', '_');
            
            % Combine them to create the default save name
            defaultFileName = sprintf('%s_%s.png', baseName, safeMethodName);
            
            % 2. Open the 'Save As' dialog with ONLY lossless formats
            [file, path] = uiputfile({'*.png', 'PNG Image (*.png)'; ...
                                      '*.tif;*.tiff', 'TIFF Image (*.tif, *.tiff)'; ...
                                      '*.bmp', 'Bitmap Image (*.bmp)'}, ...
                                      'Save Reconstructed Image', defaultFileName);
            
            % 3. Check if the user clicked 'Cancel' or closed the window
            if isequal(file, 0) || isequal(path, 0)
                return; 
            end
            
            % 4. Construct the final file path
            fullFileName = fullfile(path, file);
            
            % 5. Attempt to save the image to the drive
            try
                % Grab the matrix currently displayed on the UI
                imgData = app.Image2.ImageSource;
                
                % imwrite automatically detects the format from file extension
                imwrite(imgData, fullFileName);
                
                % Log the success to the console
                app.logMsg(['Image successfully saved to: ', fullFileName]);
                
            catch ME
                % If something goes wrong (e.g., folder requires admin privileges)
                uialert(app.UIFigure, ['Failed to save image: ', ME.message], 'Save Error');
            end
        end

        % Window key press function: UIFigure
        function UIFigureWindowKeyPress(app, event)
        % Check for Ctrl+P
            if any(strcmp(event.Modifier, 'control')) && strcmp(event.Key, 'p')
                
                % CRITICAL: Force the UI to finish all drawing tasks
                % This ensures the logo and current data are "rendered" before the snapshot
                drawnow; 
                
                fName = sprintf('GUI_Capture_%s.png', datestr(now, 'HHMMSS'));
                app.UIFigure.Pointer = 'watch';
                
                try
                    % 'BackgroundColor', 'current' ensures it doesn't force everything to white.
                    % 'Resolution', 300 makes it 3x to 4x sharper than a standard Print Screen.
                    exportgraphics(app.UIFigure, fName, ...
                        'Resolution', 300, ...
                        'BackgroundColor', 'current');
                    
                    app.logMsg(['High-res capture saved to project folder: ', fName]);
                    
                catch ME
                    uialert(app.UIFigure, 'Capture failed. Make sure the window is not minimized.', 'Export Error');
                end
                
                app.UIFigure.Pointer = 'arrow';
            end
        end

        % Menu selected function: SingleModeMenu
        function SingleModeMenuSelected(app, event)
            app.AppMode = 'Single';
            app.SingleModeMenu.Checked = 'on';
            app.BulkModeMenu.Checked = 'off';
            
            % Update UI for Single Mode
            app.OpenFileButton.Text = 'Open File';
            app.CurrentFileTextArea.Value = '';
            app.ReconstructImageButton.Enable = 'off';
            app.SaveImageButton.Enable = 'off';
            
            % --- VISIBILITY TOGGLES ---
            app.ReconstructedImagePanel.Visible = 'on'; % Show "Reconstructed Image:" title
            app.Panel.Visible = 'on'; % MUST STAY ON (It holds the image and listbox)
            app.Image2.Visible = 'off'; % Stays hidden until a reconstruction finishes
            app.BulkFileListBox.Visible = 'off'; % Hide Bulk List
            app.FilesListBoxLabel.Visible = 'off'; % Hide Bulk Label
            
            app.logMsg('Mode switched to: Single');
        end

        % Menu selected function: BulkModeMenu
        function BulkModeMenuSelected(app, event)
            app.AppMode = 'Bulk';
            app.SingleModeMenu.Checked = 'off';
            app.BulkModeMenu.Checked = 'on';
            
            % Update UI for Bulk Mode
            app.OpenFileButton.Text = 'Open Files';
            app.CurrentFileTextArea.Value = '';
            app.ReconstructImageButton.Enable = 'off';
            app.SaveImageButton.Enable = 'off';
            
            % --- VISIBILITY TOGGLES ---
            app.ReconstructedImagePanel.Visible = 'off'; % Hide "Reconstructed Image:" title
            app.Panel.Visible = 'on'; % MUST STAY ON (So the listbox is visible)
            app.Image2.Visible = 'off'; % Hide Image
            app.BulkFileListBox.Visible = 'on'; % Show Bulk List
            app.FilesListBoxLabel.Visible = 'on'; % Show Bulk Label
            
            app.BulkFileListBox.Items = {}; % Clear old items
            app.logMsg('Mode switched to: Bulk');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.WindowKeyPressFcn = createCallbackFcn(app, @UIFigureWindowKeyPress, true);
            app.ModeMenu = uimenu(app.UIFigure);
            app.ModeMenu.Text = 'Mode';
            app.SingleModeMenu = uimenu(app.ModeMenu);
            app.SingleModeMenu.MenuSelectedFcn = createCallbackFcn(app, @SingleModeMenuSelected, true);
            app.SingleModeMenu.Text = 'Single Mode';
            app.BulkModeMenu = uimenu(app.ModeMenu);
            app.BulkModeMenu.MenuSelectedFcn = createCallbackFcn(app, @BulkModeMenuSelected, true);
            app.BulkModeMenu.Text = 'Bulk Mode';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.ReconstructedImagePanel = uipanel(app.GridLayout);
            app.ReconstructedImagePanel.Title = 'Reconstructed Image:';
            app.CSCameraReconstructionSoftwareLabel = uilabel(app.GridLayout);
            app.CSCameraReconstructionSoftwareLabel.Text = 'CSCamera Reconstruction Software';
            app.Image = uiimage(app.GridLayout);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'assets', 'eng_logo.png');
            app.OpenFileButton = uibutton(app.GridLayout, 'push');
            app.OpenFileButton.ButtonPushedFcn = createCallbackFcn(app, @OpenFileButtonPushed, true);
            app.OpenFileButton.Text = 'Open File';
            app.CurrentFileTextAreaLabel = uilabel(app.GridLayout);
            app.CurrentFileTextAreaLabel.Text = 'Current File:';
            app.CurrentFileTextArea = uitextarea(app.GridLayout);
            app.CurrentFileTextArea.Editable = 'off';
            app.ReconstructionMethodLabel = uilabel(app.GridLayout);
            app.ReconstructionMethodLabel.Text = 'Select Reconstruction Method:';
            app.SelectReconstructionMethodListBox = uilistbox(app.GridLayout);
            app.SelectReconstructionMethodListBox.Items = {'Inverse Transform', 'Total Variance', 'CNN'};
            app.SelectReconstructionMethodListBox.Value = 'Inverse Transform';
            app.SaveImageButton = uibutton(app.GridLayout, 'push');
            app.SaveImageButton.ButtonPushedFcn = createCallbackFcn(app, @SaveImageButtonPushed, true);
            app.SaveImageButton.Enable = 'off';
            app.SaveImageButton.Text = 'Save Image';
            app.ReconstructImageButton = uibutton(app.GridLayout, 'push');
            app.ReconstructImageButton.ButtonPushedFcn = createCallbackFcn(app, @ReconstructImageButtonPushed, true);
            app.ReconstructImageButton.Enable = 'off';
            app.ReconstructImageButton.Text = 'Reconstruct Image';
            app.Panel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.Panel);
            app.Image2 = uiimage(app.GridLayout2);
            app.Image2.Visible = 'off';
            app.FilesListBoxLabel = uilabel(app.GridLayout2);
            app.FilesListBoxLabel.Visible = 'off';
            app.FilesListBoxLabel.Text = 'Files';
            app.BulkFileListBox = uilistbox(app.GridLayout2);
            app.BulkFileListBox.Items = {};
            app.BulkFileListBox.Visible = 'off';
            app.BulkFileListBox.Value = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Reconstruction_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end